classdef LabelRecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LabelRecognitionUIFigure    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        LeftGridLayout              matlab.ui.container.GridLayout
        FileLocationField           matlab.ui.control.EditField
        BrowseButton                matlab.ui.control.Button
        RecogniseDatesButton        matlab.ui.control.Button
        ConfigurationLabel          matlab.ui.control.Label
        MSERDeltaSliderLabel        matlab.ui.control.Label
        MSERDeltaSlider             matlab.ui.control.Slider
        SWTThresholdSliderLabel     matlab.ui.control.Label
        SWTThresholdSlider          matlab.ui.control.Slider
        OrientationThresholdSliderLabel  matlab.ui.control.Label
        OrientationThresholdSlider  matlab.ui.control.Slider
        DetectedDatesPanel          matlab.ui.container.Panel
        DetectedDatesGridLayout     matlab.ui.container.GridLayout
        SaveButton                  matlab.ui.control.Button
        TextArea                    matlab.ui.control.TextArea
        RightPanel                  matlab.ui.container.Panel
        RightGridLayout             matlab.ui.container.GridLayout
        Image                       matlab.ui.control.Image
        RuntimeLabel                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Properties that correspond to internal app data
    properties (Access = private)
        lr = LabelRecogniser();
    end
    
    % Methods that correspond to internal app behaviour
    methods (Access = private)
        
        function SelectImage(app)
            % Define valid file types
            types = {'*.jpeg;*.jpg;*.png;*.gif;*.tiff', ...
                'Image Files (*.jpeg, *.jpg, *.png, *.gif, *.tiff)'};
            % Open file explorer
            [file, path] = uigetfile(types, 'Browse Images');
            
            % Check that a file was selected
            if (~isequal(file, 0))
                % Get full path
                filepath = fullfile(path, file);
                
                % Attempt to read image to M-by-N matrix
                try 
                    I = imread(filepath);
                catch ME
                    uialert(app.LabelRecognitionUIFigure, ME.message, 'Invalid File Type');
                end
                
                % Assign selected image to variables and GUI components
                AssignImageValues(app, I, filepath);
            end
        end
        
        function AssignImageValues(app, userImage, imagePath)
            % Ensure M-by-N-by-3 matrix is always assigned to image
            if size(userImage, 3) < 3
                app.Image.ImageSource = cat(3, userImage, userImage, userImage);
            else
                app.Image.ImageSource = userImage;
            end
            % Assign selected file to field and image box
            app.FileLocationField.Value = imagePath;
            % Assign image matrix to Label Recogniser object 
            app.lr.image = userImage;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            %Select an image from explorer UI and assign to app
            SelectImage(app);
            
            % Return to UIFigure
            drawnow;
            figure(app.LabelRecognitionUIFigure);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LabelRecognitionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {234, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LabelRecognitionUIFigure = uifigure('Visible', 'off');
            app.LabelRecognitionUIFigure.Name = 'Label Recognition';
            app.LabelRecognitionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LabelRecognitionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftGridLayout = uigridlayout(app.LeftPanel);
            app.FileLocationField = uieditfield(app.LeftGridLayout, 'text');
            app.FileLocationField.Editable = 'off';
            app.FileLocationField.Tooltip = {'Image File Path'};
            app.BrowseButton = uibutton(app.LeftGridLayout, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.RecogniseDatesButton = uibutton(app.LeftGridLayout, 'push');
            app.RecogniseDatesButton.Text = 'Recognise Dates';
            app.ConfigurationLabel = uilabel(app.LeftGridLayout);
            app.ConfigurationLabel.Text = 'Configuration';
            app.MSERDeltaSliderLabel = uilabel(app.LeftGridLayout);
            app.MSERDeltaSliderLabel.Text = 'MSER Delta';
            app.MSERDeltaSlider = uislider(app.LeftGridLayout);
            app.MSERDeltaSlider.Limits = [0 1];
            app.MSERDeltaSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.SWTThresholdSliderLabel = uilabel(app.LeftGridLayout);
            app.SWTThresholdSliderLabel.Text = 'SWT Threshold';
            app.SWTThresholdSlider = uislider(app.LeftGridLayout);
            app.SWTThresholdSlider.Limits = [0 1];
            app.SWTThresholdSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.SWTThresholdSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.OrientationThresholdSliderLabel = uilabel(app.LeftGridLayout);
            app.OrientationThresholdSliderLabel.Text = 'Orientation Threshold';
            app.OrientationThresholdSlider = uislider(app.LeftGridLayout);
            app.OrientationThresholdSlider.Limits = [0 1];
            app.OrientationThresholdSlider.MajorTicks = [0 0.33 0.66 1];
            app.OrientationThresholdSlider.MajorTickLabels = {'0', '0.33', '0.66', '1'};
            app.DetectedDatesPanel = uipanel(app.LeftGridLayout);
            app.DetectedDatesPanel.Title = 'Detected Dates';
            app.DetectedDatesGridLayout = uigridlayout(app.DetectedDatesPanel);
            app.SaveButton = uibutton(app.DetectedDatesGridLayout, 'push');
            app.SaveButton.Text = 'Save...';
            app.TextArea = uitextarea(app.DetectedDatesGridLayout);
            app.TextArea.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightGridLayout = uigridlayout(app.RightPanel);
            app.Image = uiimage(app.RightGridLayout);
            app.RuntimeLabel = uilabel(app.RightGridLayout);
            app.RuntimeLabel.Text = 'Completed in: {0} secs';
            % Show the figure after all components are created
            app.LabelRecognitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabelRecognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LabelRecognitionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LabelRecognitionUIFigure)
        end
    end
end